classdef mcp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MCPUIFigure    matlab.ui.Figure
        MCPPanel       matlab.ui.container.Panel
        TabGroup       matlab.ui.container.TabGroup
        WatermarkTab   matlab.ui.container.Tab
        ButtonGroup    matlab.ui.container.ButtonGroup
        ImageButton    matlab.ui.control.Button
        AudioButton    matlab.ui.control.Button
        VideoButton    matlab.ui.control.Button
        EncryptionTab  matlab.ui.container.Tab
        ButtonGroup_3  matlab.ui.container.ButtonGroup
        ImageButton_3  matlab.ui.control.Button
        AudioButton_3  matlab.ui.control.Button
        VideoButton_3  matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: AudioButton
        function AudioButtonPushed(app, event)
            run Audio_Watermarking.m
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            
              run Image_Watermarking.m
        end

        % Callback function
        function ImageButton_2Pushed(app, event)


        end

        % Button pushed function: ImageButton_3
        function ImageButton_3Pushed(app, event)
            
            run ImageEncryptionGui.m
            
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
           
           
            %% Read video into frames
            clear all; close all; clc;            % Initialize
            
            [cover_fname, cover_pthname] = ...
    uigetfile('*.Mp4;*.mpeg', 'Select Video file');
            if (cover_fname ~= 0)
    vidname = strcat(cover_pthname, cover_fname);
               
            else
    return;
            end
            %vidname = 'Data/video.Mp4';           % Video Path
            
            [cover_fname, cover_pthname] = ...
    uigetfile('*.jpg; *.png; *.tif; *.bmp', 'Select Copyright Image');
            if (cover_fname ~= 0)
    secimage = strcat(cover_pthname, cover_fname);
               
            else
    return;
            end
            %secimage = 'Data/secret.png';         % Secret image path
            x = 0.1;                                % Embedding Strength
            
            n = 1000; 
            h = waitbar(n,'Loading Video...');
            %waitbar(x,'Loading Video...','CreateCancelBtn','button_callback') % Create figure and set starting time 
            for i = 1:n 
            pause(0.01) % Do something important 
            waitbar(i/n) % Update figure 
            
            end
            %waitbar(n,h,'Loading Completed');
            %display('Loading Video...')
            tic


            vidObj = VideoReader(vidname);  % Load video object
            
            % Video parameters
            nFrames = vidObj.NumberOfFrames;
            vidH = vidObj.Height;
            vidW = vidObj.Width;
            
            % Create empty frames
            mov(1:nFrames) = struct('cdata',zeros(vidH,vidW,3,'uint8'),'colormap',[]);
            
            % Copy video frames into mov struct
            for k = 1:nFrames
    mov(k).cdata = read(vidObj,k);
            end
            display(['Loaded ' num2str(nFrames) ' frames successfully...']);
            toc
            waitbar(n,h,['Loaded' num2str(nFrames)]);
            %% Frame by Frame Embedding
            display('Begin Embedding...')
            waitbar(n,h,'Begin Embedding');
            tic
            
            secret = imread(secimage);
            % convert image into binary (0.1 is a predetermined threshold...)
            S = im2bw(secret,0.1);
            [wmh,wmw] = size(S);
            RGB(1:nFrames) = struct('cdata',zeros(vidH,vidW,3,'uint8'),'colormap',[]);
            Yorg = zeros(vidH,vidW,nFrames);
            Yf_out = zeros(vidH,vidW,nFrames);
            for i = 1:nFrames
    YUV = convRGBYUV(mov(i).cdata);
    Yorg(:,:,i) = YUV(:,:,1);   
    % Embedding is done on the Y frame only. In the original paper, the video
    % is converted into grayscale. This makes no sense! Given the motivation
    % for the paper (which is to add a safety feature to movies), converting
    % the movie into grayscale makes no sense at all!...
    Yf_out(:,:,i) = dwt_embedd(Yorg(:,:,i),S,x);
    
    % Convert embedded frame back into RGB (for viewing purposes)
    RGB(i).cdata = uint8(convYUVRGB(cat(3,Yf_out(:,:,i),YUV(:,:,2),YUV(:,:,3)))); 
            end
            
            display(['Embedded ' num2str(nFrames) ' frames successfully...']);
            toc
            waitbar(n,h,['Embedded' num2str(nFrames) ]);
            waitbar(n,h,'Begin Extraction');
            %% Frame by Frame Extraction
            display('Begin Extracting...')
            tic
            
            wMark(1:nFrames) = struct('cdata',zeros(wmh,wmw,1,'uint8'),'colormap',[]);
            for i = 1:nFrames
    % Extraction is done using the Y frame of the YUV converted watermarked
    % video. This frame is already saved in the Yf_out variable and
    % therefore used directly. A similar result can be observed by
    % converting RGB variable frames one by one into YUV and taking the Y
    % frame as the watermarked frame. Similarly, the original Y frame is
    % stored in the Yorg variable. Those two are used as inputs to the
    % extractor! Note that the WATERMARK itself is not used at all!
    
    wMark(i).cdata = dwt_extract(Yf_out(:,:,i),Yorg(:,:,i),x,[wmh wmw]);
            end
            
            display(['Extracted ' num2str(nFrames) ' frames successfully...']);
            toc
    waitbar(n,h,['Extracted ' num2str(nFrames)]);
            %% Result Generation
            display('Generating results for each frame...')
            waitbar(n,h,'Generating results for each frame');
            close(h)
            
            % Normalized Correlation - Algorithm from the paper
            % This value will always be = 1 when no attack has occured on the
            % watermarked image set. If you want to see it degenerating, send an
            % attacked version of the video to the dwt_extract and compare results!
            
            NC = ones(1,nFrames);
            NCden = sum(sum(S.*S));
            for i = 1:nFrames
    NCnum = sum(sum(wMark(i).cdata.*S));
    if (NCden~=NCnum)
        NC(i) = NCnum/NCden;
    end
            end
            
            
            % PSNR
            % Only the Y frame of the YUV image is considered. Specific method is not
            % defined on the paper. Using the provided algorithm...
            MSE = abs((1/(vidH*vidW))*(sum(sum(Yorg - Yf_out))));
            PSNR = 10*log((255*255)/MSE);
            % Note that PSNR values, in the current configuration, will be high values.
            % Higher PSNR suggests a higher imperceptibility. Which means that, if x is
            % decreased from 10 to 0.1, PSNR will increase from ~118 to ~165. However,
            % when Yf_out is tampered with (attacked), PSNR will be lower because the
            % MSE will increase...
            
            %% Output Visualization
            
            h1=implay(mov,vidObj.FrameRate);
            h2=implay(RGB,vidObj.FrameRate);
            h3=implay(wMark,vidObj.FrameRate);
            
            
            set(h1.Parent, 'position', [150 100 vidW+10 vidH+10],'Name','Original Video')
            set(h2.Parent, 'position', [150+vidW+30 100 vidW+10 vidH+10],'Name','Embedded Video')
            set(h3.Parent, 'position', [150+2*vidW+60 100 wmh+10 wmw+10],'Name','Extracted Watermark')
            
            display('Use the movie player to watch the results...')
            
            %% Save Output
            % Uncomment the section below if you want to save your entire workspace
            % into a new folder! Note that it might be a big memory dump depending on
            % your video. It might crash the system... It is best to avoid generating
            % the output from the above section if you wish to save the output...
           % display('Saving output');
            % savpath = 'Save';
            % if ~exist(savpath,'file')
             %    mkdir(savpath)
            % end
             %A_ = clock;
             %subpath = num2str(round(A_(4)*3600+A_(5)*60+A_(6)));
             %fullpath = [savpath '\' subpath];
             %save(fullpath);
            
            
            display('............................................')
            display('...........~!Execution Complete!~...........')
            
        
        end

        % Button pushed function: VideoButton_3
        function VideoButton_3Pushed(app, event)
            run MCP.m
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MCPUIFigure = uifigure;
            app.MCPUIFigure.Name = 'MCP';
            app.MCPPanel = uipanel(app.MCPUIFigure);
            app.MCPPanel.Title = 'MCP';
            app.TabGroup = uitabgroup(app.MCPPanel);
            app.WatermarkTab = uitab(app.TabGroup);
            app.WatermarkTab.Title = 'Watermark';
            app.ButtonGroup = uibuttongroup(app.WatermarkTab);
            app.ImageButton = uibutton(app.ButtonGroup, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.AudioButton = uibutton(app.ButtonGroup, 'push');
            app.AudioButton.ButtonPushedFcn = createCallbackFcn(app, @AudioButtonPushed, true);
            app.AudioButton.Text = 'Audio';
            app.VideoButton = uibutton(app.ButtonGroup, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Text = 'Video';
            app.EncryptionTab = uitab(app.TabGroup);
            app.EncryptionTab.Title = 'Encryption';
            app.ButtonGroup_3 = uibuttongroup(app.EncryptionTab);
            app.ImageButton_3 = uibutton(app.ButtonGroup_3, 'push');
            app.ImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImageButton_3Pushed, true);
            app.ImageButton_3.Text = 'Image';
            app.AudioButton_3 = uibutton(app.ButtonGroup_3, 'push');
            app.AudioButton_3.Text = 'Audio';
            app.VideoButton_3 = uibutton(app.ButtonGroup_3, 'push');
            app.VideoButton_3.ButtonPushedFcn = createCallbackFcn(app, @VideoButton_3Pushed, true);
            app.VideoButton_3.Text = 'Video';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mcp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MCPUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MCPUIFigure)
        end
    end
end